classdef VibLoggerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DeviceListBoxLabel              matlab.ui.control.Label
        DeviceListBox                   matlab.ui.control.ListBox
        SensorConfigTable               matlab.ui.control.Table
        SamplingfrequencyHzEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyField          matlab.ui.control.NumericEditField
        BlocksizesEditFieldLabel        matlab.ui.control.Label
        BlockSizeField                  matlab.ui.control.NumericEditField
        RecordingtimesEditField_2Label  matlab.ui.control.Label
        RecordingTimeField              matlab.ui.control.NumericEditField
        LivePreview                     matlab.ui.control.CheckBox
        SaveData                        matlab.ui.control.CheckBox
        MeasurementnameEditFieldLabel   matlab.ui.control.Label
        MeasurementName                 matlab.ui.control.EditField
        ChannelsListBoxLabel            matlab.ui.control.Label
        ChannelsListBox                 matlab.ui.control.ListBox
        RunSwitchLabel                  matlab.ui.control.Label
        RunSwitch                       matlab.ui.control.Switch
        RecordingLamp                   matlab.ui.control.Lamp
        DeviceRefreshButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        DAQSession;  % Data acquisition session
        settings = struct();    % settings variable for vibLogger
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DeviceRefreshButton
        function DeviceRefreshButtonPushed(app, event)
            devices = daqlist;
            app.DeviceListBox.Items = {};
            for d=1:length(devices)
                app.DeviceListBox.Items{end+1} = devices(d).DeviceID;
                for c=1:devices.DeviceInfo(d)
                    app.ChannelsListBox %TODO: do with real device
                end
            end
        end

        % Value changed function: RunSwitch
        function RunSwitchValueChanged(app, event)
            value = app.RunSwitch.Value;
            if(value)
                app.settings.device_ids = %% TODO: build from table
                % {'cDAQ2Mod3','cDAQ2Mod4'};  
    
                % app.settingschannels{1} = [0, 1, 2];  % which channels to read on each module / device
                % app.settings.channels{2} = [0, 1, 2];  % which channels to read on each module / device

                %app.settings.sensorIDs = {'unity','unity','unity','58595','58594','50887'};  %sensor IDs (run sensors_db('list') to see all sensors)
                %app.settings.channel_names = {'Mono X','Mono Y','Mono Z','Ground X','Ground Y','Ground Z'}; %channel names

                %app.settings.channel_type = 'Voltage';  % 'Voltage' for standard voltage
                                 % 'IEPE' for IEPE / ICP sensors.
                                 % also accepts a cell of strings for
                                 % different settings per channel.
                                 
                %app.settings.iepe_excitation_current = .004;                         
                        
                %app.settings.fsamp = 2048;   % sampling frequency in Hz
                %app.settings.recording_time = 1; % time to record in seconds per block
                %app.settings.timeout = 200;   % max acquisition time in seconds
                
                %app.settings.output_folder = '.'; %where to save the results
                
                %app.settings.live_preview = true;   
                %app.settings.save_data = false;      % currently there is no way to 
                                % independently set the save and preview times

                % runs vibLogger                                
                app.DAQSession = vibLogger(app.settings);
            else
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DeviceListBoxLabel = uilabel(app.UIFigure);
            app.DeviceListBoxLabel.Text = 'Device';
            app.DeviceListBox = uilistbox(app.UIFigure);
            app.SensorConfigTable = uitable(app.UIFigure);
            app.SensorConfigTable.ColumnName = {'Device'; 'Channel'; 'Sensor ID'; 'Name'; 'Type'};
            app.SensorConfigTable.RowName = {};
            app.SamplingfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingfrequencyHzEditFieldLabel.Text = 'Sampling frequency (Hz)';
            app.SamplingFrequencyField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFrequencyField.Value = 2048;
            app.BlocksizesEditFieldLabel = uilabel(app.UIFigure);
            app.BlocksizesEditFieldLabel.Text = 'Block size (s)';
            app.BlockSizeField = uieditfield(app.UIFigure, 'numeric');
            app.BlockSizeField.Value = 60;
            app.RecordingtimesEditField_2Label = uilabel(app.UIFigure);
            app.RecordingtimesEditField_2Label.Text = 'Recording time (s)';
            app.RecordingTimeField = uieditfield(app.UIFigure, 'numeric');
            app.RecordingTimeField.Value = 300;
            app.LivePreview = uicheckbox(app.UIFigure);
            app.LivePreview.Text = 'Live preview';
            app.SaveData = uicheckbox(app.UIFigure);
            app.SaveData.Text = 'Save data';
            app.MeasurementnameEditFieldLabel = uilabel(app.UIFigure);
            app.MeasurementnameEditFieldLabel.Text = 'Measurement name';
            app.MeasurementName = uieditfield(app.UIFigure, 'text');
            app.ChannelsListBoxLabel = uilabel(app.UIFigure);
            app.ChannelsListBoxLabel.Text = 'Channels';
            app.ChannelsListBox = uilistbox(app.UIFigure);
            app.RunSwitchLabel = uilabel(app.UIFigure);
            app.RunSwitchLabel.Text = 'Run';
            app.RunSwitch = uiswitch(app.UIFigure, 'slider');
            app.RunSwitch.ValueChangedFcn = createCallbackFcn(app, @RunSwitchValueChanged, true);
            app.RecordingLamp = uilamp(app.UIFigure);
            app.DeviceRefreshButton = uibutton(app.UIFigure, 'push');
            app.DeviceRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @DeviceRefreshButtonPushed, true);
            app.DeviceRefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VibLoggerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end